classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        HuffmandecoButton  matlab.ui.control.Button
        HuffmanencoButton  matlab.ui.control.Button
        uploadImgButton    matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: uploadImgButton
        function uploadImgButtonPushed(app, event)
            global Img;
            [filename,pathname] = uigetfile('*.*',"MultiSelect");
            filename = strcat(pathname,filename);
            Img = imread(filename);
            imshow(Img,'Parent',app.UIAxes);
        end

        % Button pushed function: HuffmanencoButton
        function HuffmanencoButtonPushed(app, event)
global Img;
global m;
global n;
global hcode;
global dict;
I = Img;
disp('the pixel values are:');
disp(I);
subplot(1,2,1);
imshow(uint8(I));
title('Original image');
[m,n] = size(I);
Totalcount=m*n;
symbols = unique(I);
disp('the unique symbols are');
disp(symbols);
counts=histc(I(:),symbols);
pro=counts./Totalcount;
disp('the probabilities are:');
disp(pro);
[dict,avglen]=huffmandict(symbols,pro);
disp('the average length of the code is :');
disp(avglen);
H=entropy(uint8(I));
disp('Entropy is:');
disp(H);
E=(H/avglen)*100;
disp('Efficiency is:');
disp(E);
disp('Compression ratio is:');
Cr=(Totalcount*8)/(Totalcount*avglen)
disp('Relative data redundancy is:');
RD = (1 - (1/Cr))*100
newvec=reshape(pagetranspose(I),1,[] );
hcode=huffmanenco(newvec,dict);
disp('The Huffman encoded code is:');
disp(hcode);
        end

        % Button pushed function: HuffmandecoButton
        function HuffmandecoButtonPushed(app, event)
global m;
global n;
global hcode;
global dict;
M = m;
N = n;
Hcode = hcode;
Dict = dict;
DD1=huffmandeco(Hcode,Dict);
disp('The Huffman decoded code is:');
%disp(DD1);
DD=uint8(DD1);
Restore=reshape(DD,M,N);
subplot(1,2,2);
imshow(Restore,'Parent',app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.uploadImgButton = uibutton(app.UIFigure, 'push');
            app.uploadImgButton.ButtonPushedFcn = createCallbackFcn(app, @uploadImgButtonPushed, true);
            app.uploadImgButton.Text = 'upload Img';
            app.HuffmanencoButton = uibutton(app.UIFigure, 'push');
            app.HuffmanencoButton.ButtonPushedFcn = createCallbackFcn(app, @HuffmanencoButtonPushed, true);
            app.HuffmanencoButton.Text = 'Huffman enco';
            app.HuffmandecoButton = uibutton(app.UIFigure, 'push');
            app.HuffmandecoButton.ButtonPushedFcn = createCallbackFcn(app, @HuffmandecoButtonPushed, true);
            app.HuffmandecoButton.Text = 'Huffman deco';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end